classdef app_ver_2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        CalligraphydrawingrobotLabel  matlab.ui.control.Label
        CancelButton                  matlab.ui.control.Button
        RunButton                     matlab.ui.control.Button
        LoadButton                    matlab.ui.control.Button
        StreamAxes                    matlab.ui.control.UIAxes
        ImageAxes                     matlab.ui.control.UIAxes
    end

      properties (Access = private)
        SelectedImageFile             string
        StopFlag                      logical = false
    end

    methods (Access = private)
        
        function updateimage(app, imagefile)
            % For corn.tif, read the second image in the file 
            if strcmp(imagefile, 'corn.tif')
                im = imread('corn.tif', 2);
            else
                try
                    im = imread(imagefile);
                catch ME
                    % If problem reading image, display error message
                    uialert(app.UIFigure, ME.message, 'Image Error');
                    return;
                end            
            end 
            
            % Display the image
            imagesc(app.ImageAxes, im);
            app.ImageAxes.Visible = 'on';
            
            % Store the selected image file
            app.SelectedImageFile = imagefile;
        end
        
        function runDrawingRobot(app, imageFile)
            % Drawing robot code
            clearvars;
            sim = remApi('remoteApi');
            sim.simxFinish(-1);
            clientID = sim.simxStart('127.0.0.1',19999,true,true,5000,5);
            if (clientID > -1)
                disp('Connected to remote API server');
                [~, dum] = sim.simxGetObjectHandle(clientID, 'IRB140_target', sim.simx_opmode_blocking);
                
                J = imread(imageFile); % Use the selected image file
                K = imresize(J, 0.25);
        
                if size(K, 3) == 3
                    I = rgb2gray(K);
                else
                    I = K;
                end
                sf = 3; % upscaling factor
                sz = size(I);
                xg = 1:sz(1);
                yg = 1:sz(2);
                F = griddedInterpolant({xg, yg}, double(I));
                F.Method = 'cubic';
                xq = (1:(1/sf)*(0.53):sz(1))'; % scale to upscaling factor*1px/nm
                yq = (1:(1/sf)*(0.53):sz(2))';
                I_re = imbinarize(uint8(F({xq, yq})), 0.5); % Use imbinarize instead of im2bw
        
                % Iterative filtering algorithm
                sz = size(I_re);
                L1 = bwlabel(I_re);
                N = max(L1, [], 'all');
                windowSize = 19; 
                for k = 1:10 % k iterations
                    L1 = bwlabel(I_re);
                    I_b = zeros(sz);
                    % Retrieve each feature and save in temp array
                    for i = 1:N
                        I_temp = zeros(sz);
                        for j = 1:sz(1)
                            for jj = 1:sz(2)
                                if L1(j, jj) == i
                                    I_temp(j, jj) = I_re(j, jj);
                                end
                            end
                        end
                        % Correct windowsize for small particles
                        if sum(I_temp(:) > 0) < 1 * (windowSize^2) * pi * sf^2 
                            window = round(windowSize / 3);
                        else
                            window = windowSize;
                        end
                        kernel = ones(window) / window^2;
                        I_temp_filtered = conv2(I_temp, kernel, 'same'); % Filtering by 2D convolution
                        % Prevent merging of particles or to the edge by adding a zero
                        for j = 1:sz(1)
                            for jj = 1:sz(2)
                                if I_b(j, jj) == 0 && I_temp_filtered(j, jj) > 0
                                    I_b(j, jj) = I_temp_filtered(j, jj);
                                elseif I_b(j, jj) ~= 0 && I_temp_filtered(j, jj) ~= 0
                                    I_b(j, jj) = 0; 
                                elseif j == sz(1) || jj == sz(2)
                                    I_b(j, jj) = 0; 
                                end
                            end
                        end
                    end
                    I_re = I_b > 0.5;
                end
                I_2 = I_re;
                % Remove pixels sticking out
                I_2 = ~I_2;
                I_2 = bwmorph(I_2, 'majority');
                I_2 = ~I_2;
                I_2 = bwmorph(I_2, 'majority');
                [B, ~] = bwboundaries(I_2); % Replace 'L' with '~'
                imshow(I_2, 'Parent', app.ImageAxes);
                hold(app.ImageAxes, 'on');
        
                % Iterate through each boundary
                for k = 1:length(B)
                    boundary = B{k};
                    plot(app.ImageAxes, boundary(:, 2), boundary(:, 1), 'r', 'LineWidth', 2);
                end
                
                % Motion trajectory of robot arm 
                count = 0;
                for k = 1:length(B)
                    boundary = B{k};
                    count = count + length(boundary(:, 2));
                end
        
                x = zeros(1, count); % Preallocate arrays
                y = zeros(1, count); % Preallocate arrays
                z = zeros(1, count); % Preallocate arrays
                
                count = 0;
                for k = 1:length(B)
                    boundary = B{k};
                    for i = 1:length(boundary(:, 2))
                        count = count + 1;
                        x(count) = boundary(i, 2);
                        y(count) = boundary(i, 1);
                        z(count) = 0;
                    end
                    count = count - 1;
                    z(count) = 30;
                end
                
                for m = 1:length(x)
                    sim.simxSetObjectPosition(clientID, dum, -1, [-0.22+(x(m)*0.0008), -0.1+(y(m)*0.0008), (z(m)*0.004)+0.515], sim.simx_opmode_blocking);
                end
                sim.simxSetObjectPosition(clientID, dum, -1, [-0.4, -0.45, 0.625], sim.simx_opmode_blocking); % Move robot arm away from drawing area
            end
            sim.simxFinish(-1);
            sim.delete();
        end


        function streamDrawingProcess(app)
            % Connect to the remote API server and stream drawing process
            sim = remApi('remoteApi');
            sim.simxFinish(-1);
            clientID = sim.simxStart('127.0.0.1',19999,true,true,5000,5);
            if (clientID > -1)
                disp('Connected to remote API server');
                while ~app.StopFlag
                    [returnCode, position] = sim.simxGetObjectPosition(clientID, dum, -1, sim.simx_opmode_streaming);
                    if returnCode == sim.simx_return_ok
                        plot3(app.StreamAxes, position(1), position(2), position(3), 'ro');
                        hold(app.StreamAxes, 'on');
                        drawnow;
                    end
                end
                sim.simxFinish(-1);
            end
            sim.delete();
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Configure image axes
            app.ImageAxes.Visible = 'off';
            axis(app.ImageAxes, 'image');
        end

        % Callback function
        function DropDownValueChanged(app, event)
            
            % Update the image and histograms
            updateimage(app, app.DropDown.Value);
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            % Hiển thị hộp thoại chọn tệp
            filterspec = {'*.jpg;*.tif;*.png;*.gif', 'Tất cả các Tệp Ảnh'};
            [filename, path] = uigetfile(filterspec);
            
            % Kiểm tra xem người dùng có chọn tệp hay không
            if ischar(path)
                % Tạo đường dẫn đầy đủ đến file ảnh
                imageFile = fullfile(path, filename);
                
                % Cập nhật ảnh trong ứng dụng
                updateimage(app, imageFile);
                
                % Lưu đường dẫn của file ảnh vào biến SelectedImageFile của ứng dụng
                app.SelectedImageFile = imageFile;
            end
        end

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            app.StopFlag = false;
            
            % Kiểm tra xem biến SelectedImageFile có giá trị hay không
            if isempty(app.SelectedImageFile)
                uialert(app.UIFigure, 'Không có ảnh nào được chọn.', 'Lỗi');
                return; % Thoát khỏi hàm nếu không có ảnh được chọn
            end
            
            % Chỉ gọi hàm runDrawingRobot nếu SelectedImageFile đã được gán giá trị
            if ~isempty(app.SelectedImageFile)
                % Gọi hàm runDrawingRobot và truyền vào đường dẫn của file ảnh
                runDrawingRobot(app, app.SelectedImageFile);
                
                % Bắt đầu stream quá trình vẽ
                streamDrawingProcess(app);
            end
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            app.StopFlag = true;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Image Histograms';
            app.ImageAxes = uiaxes(app.UIFigure);
            app.ImageAxes.XTick = [];
            app.ImageAxes.XTickLabel = {'[ ]'};
            app.ImageAxes.YTick = [];
            app.StreamAxes = uiaxes(app.UIFigure);
            app.StreamAxes.XTick = [];
            app.StreamAxes.XTickLabel = {'[ ]'};
            app.StreamAxes.YTick = [];
            app.LoadButton = uibutton(app.UIFigure, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Chọn chữ muốn viết';
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.CancelButton = uibutton(app.UIFigure, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.CalligraphydrawingrobotLabel = uilabel(app.UIFigure);
            app.CalligraphydrawingrobotLabel.Text = 'Calligraphy drawing robot';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_ver_2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end